classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectthecoloryouwishtofindDropDownLabel  matlab.ui.control.Label
        SelectthecoloryouwishtofindDropDown  matlab.ui.control.DropDown
        PleaseselectacolorLabel    matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        thenhitstarttoruntheprogramLabel  matlab.ui.control.Label
        TakebackgroundImageButton  matlab.ui.control.Button
        TakenewmarkerlocationimageButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
       color_selected
       gameState = struct;
    end
  
    methods (Access = public)
        
        function img = image_taker(app)
            % Setting up webcam properties
            cam_list = webcamlist;
            cam_name = cam_list{2};
            cam = webcam(cam_name); 
            img = snapshot(cam);
        end
        
        function [Binary, STATS] = img2binary(app, Background, Markers)
            [height,width,depth] = size(Background);
            [height_m,width_m,depth_m] = size(Markers);
            Image_BackgroundSub = Background - Markers;
%             figure();
%             imshow(Image_BackgroundSub)
            
                
            % Compute Binary Image for imageprops
            Image_BackgroundSub2 = Image_BackgroundSub;
            for i=1:height
                for j=1:width
                    if ((Image_BackgroundSub(i,j,1) > 50) || ...
                       (Image_BackgroundSub(i,j,2) > 50) || ...
                       (Image_BackgroundSub(i,j,3) > 50))
                        %Will Show in Green
                        Image_BackgroundSub2(i,j,:) = [175,200,175];
                    end
                end
            end
            
            %Convert subtracted image to binary
            Binary_1 = im2bw(Image_BackgroundSub2);
          
            SE = strel('disk',5);
            Binary = imerode(Binary_1, SE);
%             figure();
%             imshow(Binary);
%             title('Binary Image Erosion');
            % Stores data on centroids
            STATS = regionprops(Binary, 'centroid');
        end  
        
        function centroid_locations = centroid_finder(app, STATS)
            %Finds Centroid Values for each shape
            items = size(STATS);
            centroid_locations = [];
            for i = 1:items
                y_temp = STATS(i).Centroid(1);
                x_temp = STATS(i).Centroid(2);
        
                %Stores locations of each centroid
                if x_temp >= 640
                    x_temp = 0;
                    y_temp = 0;
                end
                temp = [x_temp, y_temp];
                centroid_locations = [centroid_locations; temp];
            end
        end


        function rgb_centroid_values = rgb_finder(app, centroid_locations, Image_Markers)
            %Determine the RGB values associated with each 3x3 around the centroid
            items = size(centroid_locations);
            rgb_centroid_values = [];
            for i = 1:items(1)
                x_temp = round(centroid_locations(i,1));
                y_temp = round(centroid_locations(i,2));
        
                r_sum = 0;
                g_sum = 0;
                b_sum = 0;
                
                %Double for loop to average 9 different pixel values
                for j = -1:1
                    for g = -1:1
                        %Looks for RGB values associated with each centroid. (y,x)
                        r_temp = int16(Image_Markers(round(x_temp) + j, round(y_temp) + g, 1));
                        g_temp = int16(Image_Markers(round(x_temp) + j, round(y_temp) + g, 2));
                        b_temp = int16(Image_Markers(round(x_temp) + j, round(y_temp) + g, 3));
                        r_sum = r_sum + int16(r_temp);
                        g_sum = g_sum + int16(g_temp);
                        b_sum = b_sum + int16(b_temp);
                    end
                    if j==1 && g==1
                        r_temp = r_sum / 9;
                        g_temp = g_sum / 9;
                        b_temp = b_sum / 9;
                    end
                end
                temp_rgb = [r_temp, g_temp, b_temp];
                rgb_centroid_values = [rgb_centroid_values; temp_rgb];
            end
        end
        
        function colors = color_finder(app, rgb_centroid_values)
            % Determine each color by setting thresholds for each color
            marker_num = size(rgb_centroid_values);
            colors =[];
            for i = 1:marker_num(1)
                if (rgb_centroid_values(i, 1) >= 180) && (rgb_centroid_values(i, 2) >= 180)
                    colors = [colors; "yellow"];
        
                elseif ((rgb_centroid_values(i, 2) <= 150) && (rgb_centroid_values(i, 1)>= 180))
                    colors = [colors; "red"];
        
                elseif ((rgb_centroid_values(i, 1) < 150) && (rgb_centroid_values(i,3) >= 150))
                    colors = [colors; "blue"]; 
                else
                    colors = [colors; "green"];
                end
            end
        end
        
        function [angle, wells] = angle_finder(app, centroid_locations)
            angle = [];
            wells = [];
            marker_num = size(centroid_locations);
            for i = 1:marker_num
                y_displacement_temp = 240 - centroid_locations(i,1);
                x_displacement_temp = 320 - centroid_locations(i,2);
                marker_temp = atan2d(y_displacement_temp, x_displacement_temp);
                if marker_temp >= 0
                    if marker_temp <= 45
                        well_pos = 1;
                    elseif (marker_temp >= 45) && (marker_temp <=90)
                        well_pos = 2;
                    elseif (marker_temp >= 90) && (marker_temp <=135)
                        well_pos = 3;
                    elseif (marker_temp >= 135) && (marker_temp <=180)
                        well_pos = 4;
                    end
                else
                    if marker_temp >= -45
                        well_pos = 8;
                    elseif (marker_temp <= -45) && (marker_temp >= -90)
                        well_pos = 7;
                    elseif (marker_temp <= -90) && (marker_temp >= -135)
                        well_pos = 6;
                    elseif (marker_temp <= -135) && (marker_temp >= -180)
                        well_pos = 5;
                    end
                end
                angle = [angle; marker_temp];
                wells = [wells, well_pos];
            end
        end

        
        function plot_centroids(app, Image_Markers, centroid_locations, centroid_colors)
            %Plots the original image with each centroid, x,y of centroid, and its
            %color
            figure();
            imshow(Image_Markers)
            hold on;
            plot(320,240,'kO','MarkerFaceColor','k');
            
            items = size(centroid_locations);
            for i = 1:items(1)
                y_temp = centroid_locations(i,2);
                x_temp = centroid_locations(i,1);
                plot(y_temp, x_temp,'kO','MarkerFaceColor','k');
                text(y_temp+20, x_temp, sprintf('%0.0f, %0.0f', round(y_temp), round(x_temp)))
                text(y_temp+20, x_temp+20, sprintf('%s', centroid_colors(i)))
                text(y_temp, x_temp-20, sprintf('%0.0f',i));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % SelectthecoloryouwishtofindDropDown
        function SelectthecoloryouwishtofindDropDownValueChanged(app, event)
            app.color_selected = app.SelectthecoloryouwishtofindDropDown.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Create our structure for housing all data
%             gameState = struct;
            
            %Read in both images
            Image_Background = imread('gameboard_test.png');
            Image_Markers = imread('markers_test.png');
            
            [Binary_Image, STATS] = img2binary(app, Image_Background, Image_Markers);
            %Store centroid Locations
            app.gameState.centroid_locations = centroid_finder(app, STATS);
            %Finds the RGB values associated with each centroid
            app.gameState.rgb_centroid_values = rgb_finder(app, app.gameState.centroid_locations, Image_Markers);
            %Finds the Color of each shape
            app.gameState.centroid_colors = color_finder(app, app.gameState.rgb_centroid_values);
            %Determines the angle of each centroid
            [app.gameState.centroid_angles, app.gameState.centroid_well] = angle_finder(app, app.gameState.centroid_locations);
            %Plot the original image, its centroid, location, and color
            plot_centroids(app, Image_Markers, app.gameState.centroid_locations, app.gameState.centroid_colors);
        end

        % Button pushed function: TakebackgroundImageButton
        function TakebackgroundImageButtonPushed(app, event)
            img = image_taker(app);
            imwrite(img, 'gameboard_test.png')
        end

        % Button pushed function: TakenewmarkerlocationimageButton
        function TakenewmarkerlocationimageButtonPushed(app, event)
            img = image_taker(app);
            imwrite(img, 'markers_test.png')  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectthecoloryouwishtofindDropDownLabel = uilabel(app.UIFigure);
            app.SelectthecoloryouwishtofindDropDownLabel.Text = 'Select the color you wish to find';
            app.SelectthecoloryouwishtofindDropDown = uidropdown(app.UIFigure);
            app.SelectthecoloryouwishtofindDropDown.Items = {'Red', 'Green', 'Yellow', 'Blue'};
            app.SelectthecoloryouwishtofindDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectthecoloryouwishtofindDropDownValueChanged, true);
            app.SelectthecoloryouwishtofindDropDown.Value = 'Red';
            app.PleaseselectacolorLabel = uilabel(app.UIFigure);
            app.PleaseselectacolorLabel.Text = 'Please select a color';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.thenhitstarttoruntheprogramLabel = uilabel(app.UIFigure);
            app.thenhitstarttoruntheprogramLabel.Text = 'then hit start to run the program';
            app.TakebackgroundImageButton = uibutton(app.UIFigure, 'push');
            app.TakebackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @TakebackgroundImageButtonPushed, true);
            app.TakebackgroundImageButton.Text = 'Take background Image';
            app.TakenewmarkerlocationimageButton = uibutton(app.UIFigure, 'push');
            app.TakenewmarkerlocationimageButton.ButtonPushedFcn = createCallbackFcn(app, @TakenewmarkerlocationimageButtonPushed, true);
            app.TakenewmarkerlocationimageButton.Text = 'Take new marker location image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end