classdef project_4_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InitializetheSystemButton   matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        SolveTimesTextAreaLabel     matlab.ui.control.Label
        SolveTimesTextArea          matlab.ui.control.TextArea
        StartButton                 matlab.ui.control.Button
        GameboardControlPanelLabel  matlab.ui.control.Label
        ResetGameButton             matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Label_3                     matlab.ui.control.Label
        Lamp_3                      matlab.ui.control.Lamp
        menuofcolumn3Label          matlab.ui.control.Label
        InitializationwillstartthemotorandtakeLabel  matlab.ui.control.Label
        abackgroundimageofthegameboardLabel  matlab.ui.control.Label
        WheninitializationiscompletethegreenlightLabel  matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
    end

    
    properties (Access = public)
       desired_data
       actual_data
       gameState = struct;
       well_degrees = [0 45 90 135 180 225 270 315];
       %motor_degree = [30 75 120 165 210 255 300 345];
       motor_degree = [25 70 115 160 205 250 295 340]
       rest_position = 0;
       next_well_input = 0;
    end
  
    methods (Access = public)
        
        function img = image_taker(app)
            % Setting up webcam properties
            cam_list = webcamlist;
            cam_name = cam_list{2};
            cam = webcam(cam_name); 
            img = snapshot(cam);
        end
        
        function [Binary, STATS, box] = img2binary(app, Background, Markers)
            [height,width,depth] = size(Background);
            [height_m,width_m,depth_m] = size(Markers);
            Image_BackgroundSub = Background - Markers;
            %figure();
            %imshow(Image_BackgroundSub)
            
                
            % Compute Binary Image for imageprops
            Image_BackgroundSub2 = Image_BackgroundSub;
            for i=1:height
                for j=1:width
                    if ((Image_BackgroundSub(i,j,1) > 50) || ...
                       (Image_BackgroundSub(i,j,2) > 50) || ...
                       (Image_BackgroundSub(i,j,3) > 50))
                        %Will Show in Green
                        Image_BackgroundSub2(i,j,:) = [175,200,175];
                    end
                end
            end
            
            %Convert subtracted image to binary
            Binary_1 = im2bw(Image_BackgroundSub2);
          
            SE = strel('disk',5);
            Binary = imerode(Binary_1, SE);
            %figure();
            %imshow(Binary);
            %title('Binary Image Erosion');
            SE2 = strel('disk',5);
            Image_Dilate = imdilate(Binary, SE2);
            % Stores data on centroids
            STATS = regionprops(Image_Dilate, 'centroid');
            box = regionprops(Image_Dilate, 'BoundingBox');
        end  
        
        function centroid_locations = centroid_finder(app, STATS)
            %Finds Centroid Values for each shape
            items = size(STATS);
            centroid_locations = [];
            for i = 1:items
                y_temp = STATS(i).Centroid(1);
                x_temp = STATS(i).Centroid(2);
        
                %Stores locations of each centroid
                if x_temp >= 640
                    x_temp = 0;
                    y_temp = 0;
                end
                temp = [x_temp, y_temp];
                centroid_locations = [centroid_locations; temp];
            end
        end


        function rgb_centroid_values = rgb_finder(app, centroid_locations, box, Image_Markers)
            %Determine the RGB values associated with each 3x3 around the centroid
            items = size(centroid_locations);
            rgb_centroid_values = [];
            for i = 1:items(1)
                iteration = 0;
                r_sum = 0;
                g_sum = 0;
                b_sum = 0;
                
                for j = 0:round(box(i).BoundingBox(3))-1
                    for k = 0:round(box(i).BoundingBox(4))-1
                        r_temp = int32(Image_Markers(round(box(i).BoundingBox(2)) + k, round(box(i).BoundingBox(1)) + j, 1));
                        g_temp = int32(Image_Markers(round(box(i).BoundingBox(2)) + k, round(box(i).BoundingBox(1)) + j, 2));
                        b_temp = int32(Image_Markers(round(box(i).BoundingBox(2)) + k, round(box(i).BoundingBox(1)) + j, 3));
                        if (r_temp <= 220) && (g_temp <= 220) && (b_temp <= 220)
                            r_sum = r_sum + int32(r_temp);
                            g_sum = g_sum + int32(g_temp);
                            b_sum = b_sum + int32(b_temp);
                            iteration = iteration + 1;
                        end
                    end
                end
                r_avg = r_sum / iteration;
                g_avg = g_sum / iteration;
                b_avg = b_sum / iteration;
                temp_rgb = [r_avg, g_avg, b_avg];
                rgb_centroid_values = [rgb_centroid_values; temp_rgb];
            end
        end
        
        function colors = color_finder(app, rgb_centroid_values)
            % Determine each color by setting thresholds for each color
            marker_num = size(rgb_centroid_values);
            colors =[];
            for i = 1:marker_num(1)
                if (rgb_centroid_values(i, 1) >= 180) && (rgb_centroid_values(i, 2) >= 150)
                    colors = [colors; "yellow"];
        
                elseif ((rgb_centroid_values(i, 2) <= 150) && (rgb_centroid_values(i, 1)>= 100))
                    colors = [colors; "red"];
                    
                elseif ((rgb_centroid_values(i, 2)<=120) && (rgb_centroid_values(i, 1)<=120) && (rgb_centroid_values(i, 3)<=120))
                    colors = [colors; "green"];
                else
                    colors = [colors; "unknown"];
                end
            end
        end
        
        function [angle, wells] = angle_finder(app, centroid_locations)
            angle = [];
            wells = [];
            marker_num = size(centroid_locations);
            for i = 1:marker_num
                y_displacement_temp = 240 - centroid_locations(i,1);
                x_displacement_temp = 320 - centroid_locations(i,2);
                marker_temp = atan2d(y_displacement_temp, x_displacement_temp);
                if (marker_temp >= -10) && (marker_temp <= 10)
                    well_pos = 3;
                elseif marker_temp >= 0
                    if marker_temp <= 60
                        well_pos = 2;
                    elseif (marker_temp >= 60) && (marker_temp <=110)
                        well_pos = 1;
                    elseif (marker_temp >= 110) && (marker_temp <=145)
                        well_pos = 8;
                    elseif (marker_temp >= 145)
                        well_pos = 7;
                    end
                else
                    if (marker_temp >= -60)
                        well_pos = 4;
                    elseif (marker_temp <= -60) && (marker_temp >= -100)
                        well_pos = 5;
                    elseif (marker_temp <= -100) && (marker_temp >= -145)
                        well_pos = 6;
                    elseif (marker_temp >= -185) && (marker_temp <= -145)
                        well_pos = 7;
                    end
                end
                angle = [angle; marker_temp];
                wells = [wells, well_pos];
            end
        end

        function table = table_plotter(app, centroid_color, centroid_well)
            %Run for the number of markers used
            for i = 1:length(centroid_well)
                temp_table(centroid_well(i)) = centroid_color(i);
            end
            %Fills in missing values
            missing_t = ismissing(temp_table);
            for i=1:8
                if missing_t(i) == 1
                    temp_table(i)='empty';
                end
            end
            % Store Updated locations in GUI table
            update_table = cellstr(temp_table);
            table = temp_table;
            app.UITable.Data(:,2) = update_table;
        end
    
        function plot_centroids(app, Image_Markers, centroid_locations, centroid_colors)
            %Plots the original image with each centroid, x,y of centroid, and its
            %color
            figure();
            imshow(Image_Markers)
            hold on;
            plot(320,240,'kO','MarkerFaceColor','k');
            
            items = size(centroid_locations);
            for i = 1:items(1)
                y_temp = centroid_locations(i,2);
                x_temp = centroid_locations(i,1);
                plot(y_temp, x_temp,'kO','MarkerFaceColor','k');
                text(y_temp+20, x_temp, sprintf('%0.0f, %0.0f', round(y_temp), round(x_temp)))
                text(y_temp+20, x_temp+20, sprintf('%s', centroid_colors(i)))
                text(y_temp, x_temp-20, sprintf('%0.0f',i));
            end
        end
        
        function lcns = background_sub_locations(app)
            %img = image_taker(app);
            %imwrite(img, 'markers_test_1.png')  
            
            %Read in both images
            Image_Background = imread('gameboard_test_1.png');
            Image_Markers = imread('markers_test_1.png');
            
            [Binary_Image, STATS, box] = img2binary(app, Image_Background, Image_Markers);
            %Store centroid Locations
            app.gameState.centroid_locations = centroid_finder(app, STATS);
            %Finds the RGB values associated with each centroid
            app.gameState.rgb_centroid_values = rgb_finder(app, app.gameState.centroid_locations, box, Image_Markers);
            %Finds the Color of each shape
            app.gameState.centroid_colors = color_finder(app, app.gameState.rgb_centroid_values);
            %Determines the angle of each centroid
            [app.gameState.centroid_angles, app.gameState.centroid_well] = angle_finder(app, app.gameState.centroid_locations);
            %Plot the original image, its centroid, location, and color
            %plot_centroids(app, Image_Markers, app.gameState.centroid_locations, app.gameState.centroid_colors);
            
            % Return Locations of markers to table
            lcns = table_plotter(app, app.gameState.centroid_colors, app.gameState.centroid_well);
        end
        
        function motor_mover(app)
            moveable_data = string(app.actual_data); % Stored image recognition data into another variable
            for i = 1:8
                
                correct_list = moveable_data == app.desired_data; % Stores values in correct location as 1
                empty_wells = "empty" == moveable_data; % Stores values empty as 1
                moveable_list_color_names = strings; % Stores colors of okay to move washers
                
                for j = 1:8
                    if correct_list(j) == 0 || empty_wells(j) == 1
                        moveable_list_color_names(j) = moveable_data;
                    else
                        moveable_list_color_names(j) = "";
                    end
                end
            
                if correct_list(i) == 0
                    if empty_wells(i) == 0 && app.desired_data(i) ~= "empty"
                        %set_param('model/Input_degree','Value', num2str(app.motor_degree(i)))
                        pause(2)
                        %turn on EMAG and move motor down to pick up.
                        empty_index = find(empty_wells == 1);
                        pause(1)
                        % move motor up
                        pause(2)
                        %set_param('model/Input_degree','Value', num2str(app.motor_degree(empty_index(1)))
                        pause(2)
                        %move motor down to drop and turn off EMAG.
                        pause(1)
                        % move motor up
                        empty_wells(empty_index(1)) = 0;
                        want_index = find(moveable_list_color_names == app.desired_data(i));
                        pause(2)
                        %set_param('model/Input_degree','Value', num2str(app.motor_degree(want_index(1)))
                        pause(2)
                        % turn on EMAG and move motor down to pick up.
                        pause(1)
                        % Move motor up
                        pause(2)
                        %set_param('model/Input_degree','Value', num2str(app.motor_degree(i)))
                        pause(2)
                        %move motor down to drop and turn off EMAG.
                        pause(1)
                        % move motor up
                        moveable_data(empty_index(1)) = moveable_data(i); % Set the empty to index to unwated color
                        moveable_data(want_index(1)) = "empty";
                        moveable_data(i) = app.desired_data(i); % Set the inxed to corrected color
                    elseif empty_wells(i) == 1
                        want_index = find(moveable_list_color_names == app.desired_data(i));
                        
                        %set_param('model/Input_degree','Value', num2str(app.motor_degree(want_index(1)))
                        pause(2)
                        %turn on EMAG and move motor down to pick up.
                        pause(2)
                        % move motor up
                        pause(1)
                        %set_param('model/Input_degree','Value', num2str(app.motor_degree(i)))
                        pause(2)
                        %move motor down to drop and turn off EMAG.
                        pause(1)
                        % move motor up
                        
                        moveable_data(want_index(1)) = "empty";
                        moveable_data(i) = app.desired_data(i); % Set the inxed to corrected color
                    elseif app.desired_data(i) == "empty"
                        want_index = find(moveable_list_color_names == app.desired_data(i));
                        
                        %set_param('model/Input_degree','Value', num2str(app.motor_degree(app.motor_degree(i)))
                        pause(2)
                        %turn on EMAG and move motor down to pick up.
                        pause(1)
                        % move motor up
                        pause(2)
                        %set_param('model/Input_degree','Value', num2str(want_index(1)))
                        pause(2)
                        % move motor down drop washer
                        pause(1)
                        % move motor up
                        
                        moveable_data(want_index(1)) = moveable_data(i); % Index taken from set to empty
                        moveable_data(i) = app.desired_data(i); % Set the inxed to corrected color
                    end
                end
            end
            
            %set_param('model/Input_degree','Value', num2str(0))
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = {0 '' ''; 45 '' ''; 90 '' ''; 135 '' ''; 180 '' ''; 225 '' ''; 270 '' '' ;315 '' ''};
            app.UITable.ColumnFormat = ({[] [] {'empty' 'red' 'green' 'yellow'}});
            app.UITable.ColumnEditable = [false, false, true];
            app.Lamp.Enable = 'off';
            app.Lamp_3.Enable = 'off';
        end

        % Button pushed function: InitializetheSystemButton
        function InitializetheSystemButtonPushed(app, event)
            %set_param(gcs, 'SimulationCommand','start')
            img = image_taker(app);
            imwrite(img, 'gameboard_test_1.png')
            app.Lamp_3.Enable = 'on';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            initial_time = tic;
            app.desired_data = app.UITable.Data(:,3);
            %app.SolveTimesTextArea.Value = sprintf('%0.2s',app.retrieve_data{1});
            %app.actual_data = background_sub_locations(app);
            
            %motor_mover(app)
            
            final_time = toc(initial_time);
            app.SolveTimesTextArea.Value = sprintf('%0.2f',final_time);
            app.Lamp.Enable = 'on';
        end

        % Callback function
        function NextWellButtonPushed(app, event)
            app.next_well_input = 1;
        end

        % Button pushed function: ResetGameButton
        function ResetGameButtonPushed(app, event)
            app.UITable.Data = {0 '' ''; 45 '' ''; 90 '' ''; 135 '' ''; 180 '' ''; 225 '' ''; 270 '' '' ;315 '' ''};
            app.Lamp.Enable = 'off';
            app.Lamp_3.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitializetheSystemButton = uibutton(app.UIFigure, 'push');
            app.InitializetheSystemButton.ButtonPushedFcn = createCallbackFcn(app, @InitializetheSystemButtonPushed, true);
            app.InitializetheSystemButton.Text = 'Initialize the System';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Location in Degrees'; 'Actual Color of Marker'; 'Desired Color'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SolveTimesTextAreaLabel = uilabel(app.UIFigure);
            app.SolveTimesTextAreaLabel.Text = 'Solve Time (s)';
            app.SolveTimesTextArea = uitextarea(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GameboardControlPanelLabel = uilabel(app.UIFigure);
            app.GameboardControlPanelLabel.Text = 'Gameboard Control Panel';
            app.ResetGameButton = uibutton(app.UIFigure, 'push');
            app.ResetGameButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGameButtonPushed, true);
            app.ResetGameButton.Text = 'Reset Game';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.Lamp = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {''; ''};
            app.Lamp_3 = uilamp(app.UIFigure);
            app.menuofcolumn3Label = uilabel(app.UIFigure);
            app.menuofcolumn3Label.Text = 'For each marker, please select red, green, yellow, or empty from the drop down menu of column 3. ';
            app.InitializationwillstartthemotorandtakeLabel = uilabel(app.UIFigure);
            app.InitializationwillstartthemotorandtakeLabel.Text = 'Initialization will start the motor and take';
            app.abackgroundimageofthegameboardLabel = uilabel(app.UIFigure);
            app.abackgroundimageofthegameboardLabel.Text = 'a background image of the gameboard.';
            app.WheninitializationiscompletethegreenlightLabel = uilabel(app.UIFigure);
            app.WheninitializationiscompletethegreenlightLabel.Text = 'After a task has been completed, the green light next to the item will turn on.';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Please initialize the system then select the desired washer color for each well';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'There are a total of 3 red, 3 green, or 2 yellow markers. There must be atleast one well empty.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_4_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end